classdef CWApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        RecordButton                   matlab.ui.control.Button
        StopButton                     matlab.ui.control.Button
        ExitButton                     matlab.ui.control.Button
        Image                          matlab.ui.control.Image
        UltrasonicCWDopplerRADARLabel  matlab.ui.control.Label
        MaxspeedmsLabel                matlab.ui.control.Label
        output                         matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
    end

    properties (Constant)
        TimeDuration_s = 300;
        Fs = 192e3;
        Ts = 1/CWApp.Fs;                           % Sampling period
        t = 0:CWApp.Ts:(CWApp.TimeDuration_s);           % time vector for pulse 
        Fc_Hz = 8e3;                        % [Hz]
        TxSignal = sin(2*pi*CWApp.Fc_Hz*CWApp.t);  
        update_rate = 5; %Rate at which callback is called, i.e. rate at which max speed is calculated, also the interval over which its calculated.
        v_factor = (343/CWApp.Fc_Hz)/2;
        player = audioplayer(CWApp.TxSignal, CWApp.Fs, 24);
        audio_recorder = audiorecorder(CWApp.Fs, 24, 1);
    end
    
    properties (Access = private)
        max = 0;
    end
    
    methods (Access = private)
        
        function audioRecorderTimerCallback(app, obj, event, audio_recorder)         
            try
                data_format = 'double';
            
                %# stop the recorder, grab the data, restart the recorder. May miss some data
                stop(obj); %can comment this and record(obj) out to store all data received.
                data = getaudiodata(obj, data_format);
                record(obj);
                
%                 plot(data)
                [TxSignal, data, data_bpf, data_notch, data_shifted, data_out, t] = processDoppler(app.Fs, app.Fc_Hz, app.update_rate, app.TxSignal, data);
                [P1,F,T]=pspectrum(data_out,app.Fs/8,'spectrogram','FrequencyResolution',128, 'FrequencyLimits',[0 4000],'MinThreshold',-70);
%                 pspectrum(data_out,app.Fs/8,'spectrogram','FrequencyResolution',256,'FrequencyLimits',[0 4000], 'MinThreshold',-80);

                dv1=medfreq(P1,F)*app.v_factor;
                for i = 1:length(dv1)
                    if dv1(i) < 1
                        dv1(i) = 0;
                    end
                end
                dv1= smoothdata(dv1,'movmean',5);
                plot(app.UIAxes, T, abs(dv1));
                app.max = max(abs(dv1),[], 'omitnan');
                app.output.Text = num2str(app.max);
                yline(app.UIAxes, app.max, 'r');  
                yline(app.UIAxes, mean(abs(dv1), 'omitnan'), 'b--');
            catch
                %# Stop the recorder and exit
                stop(obj);
                clear sound;
                rethrow(lasterror);
            end    
            drawnow;
        end
    end
   

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            set(app.audio_recorder, 'TimerFcn', {@app.audioRecorderTimerCallback, app.audio_recorder});
            set(app.audio_recorder, 'TimerPeriod', app.update_rate);
            set(app.audio_recorder, 'BufferLength', app.update_rate);
        end

        % Button pushed function: RecordButton
        function RecordButtonPushed(app, event)
            play(app.player);
            record(app.audio_recorder);
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            stop(app.player);
            stop(app.audio_recorder);
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            stop(app.player);
            stop(app.audio_recorder);
            clear all;
            close all;
            clc;
            closereq
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            stop(app.player);
            stop(app.audio_recorder);
            clear all;
            close all;
            clc;
            closereq
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.RecordButton = uibutton(app.UIFigure, 'push');
            app.RecordButton.ButtonPushedFcn = createCallbackFcn(app, @RecordButtonPushed, true);
            app.RecordButton.Text = 'Record';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'uct.png';
            app.UltrasonicCWDopplerRADARLabel = uilabel(app.UIFigure);
            app.UltrasonicCWDopplerRADARLabel.Text = 'Ultrasonic CW Doppler RADAR';
            app.MaxspeedmsLabel = uilabel(app.UIFigure);
            app.MaxspeedmsLabel.Text = 'Max speed (m/s):';
            app.output = uilabel(app.UIFigure);
            app.output.Text = '';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Instantaneous Velocity')
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'Velocity (m/s)')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CWApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end