classdef Demonstrator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Panel                           matlab.ui.container.Panel
        UserParametersLabel             matlab.ui.control.Label
        Panel2                          matlab.ui.container.Panel
        SamplingFrequencyDropDownLabel  matlab.ui.control.Label
        SamplingFrequencyDropDown       matlab.ui.control.DropDown
        TransmitFrequencyHzEditFieldLabel  matlab.ui.control.Label
        TransmitFrequencyHzEditField    matlab.ui.control.NumericEditField
        SamplingTimesEditFieldLabel     matlab.ui.control.Label
        SamplingTimesEditField          matlab.ui.control.NumericEditField
        Panel2_2                        matlab.ui.container.Panel
        SamplesperFrameDropDownLabel    matlab.ui.control.Label
        SamplesperFrameDropDown         matlab.ui.control.DropDown
        FrameOverlapEditFieldLabel      matlab.ui.control.Label
        FrameOverlapEditField           matlab.ui.control.NumericEditField
        YAxisVariableDropDownLabel      matlab.ui.control.Label
        YAxisVariableDropDown           matlab.ui.control.DropDown
        ThresholddBLabel                matlab.ui.control.Label
        ThresholdEdit                   matlab.ui.control.NumericEditField
        LoadDataButton                  matlab.ui.control.Button
        RecordDataButton                matlab.ui.control.Button
        Image                           matlab.ui.control.Image
        SavedataCheckBox                matlab.ui.control.CheckBox
        CalibrateButton                 matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        VelocityResultsTab              matlab.ui.container.Tab
        MaxSpeedmsLabel                 matlab.ui.control.Label
        max                             matlab.ui.control.Label
        AverageSpeedmsLabel             matlab.ui.control.Label
        avg                             matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        TimeDomainTab                   matlab.ui.container.Tab
        SignalProcessingTab             matlab.ui.container.Tab
    end

    
    properties (Access = private)
        pthreshold = -90; %threshold for pspectrum method
        threshold = -20 % noise threshold is either -43 or -100 depending on acoustic SONAR or ultrasonic
    end
    
    methods (Access = private)
        
        function plotFFT(app, TxSignal, data, data_bpf, data_notch, data_shifted, data_out, f_axis, f_axis_d, Ts, Ts_d)
            %Function accepts outputs from processDoppler function and
            %plots outputs at various stages.
            ax1 = subplot(6,1,1, 'parent', app.SignalProcessingTab);
            plot(ax1, f_axis/1e3, abs(fftshift(real(fft(TxSignal))))/length(TxSignal)); %Get correct amplitude by multipling by delta t - time domain sample spacing.
            title(ax1, "FFT of Transmitted Signal");
            ylabel("Magnitude");
            ax2 = subplot(6,1,2,'parent', app.SignalProcessingTab);
            plot(ax2, f_axis/1e3, fftshift(abs(fft(data)))/length(data));
            title(ax2, "FFT of received signal");
            ax3 = subplot(6,1,3,'parent', app.SignalProcessingTab);
            plot(ax3, f_axis/1e3, abs(fftshift(real(fft(data_bpf))))/length(data_bpf));
            title(ax3, "FFT of DC filtered signal");
            ax4 = subplot(6,1,4,'parent', app.SignalProcessingTab);
            plot(ax4, f_axis/1e3, (fftshift(abs(fft(data_notch))))/length(data_notch));
            title(ax4, "FFT of notch filtered signal");
            ax5 = subplot(6,1,5, 'parent', app.SignalProcessingTab);
            plot(ax5, f_axis/1e3, (fftshift(abs(fft(data_shifted))))/length(data_shifted));
            title(ax5, "FFT of baseband shifted notch filtered signal");
            xlabel(ax5,"Frequency (kHz)");
            ax6 = subplot(6,1,6, 'parent', app.SignalProcessingTab);
            plot(ax6, f_axis_d/1e3, (fftshift(abs(fft(data_out))))/length(data_out));
            title(ax6, "FFT of final downsampled signal - doppler frequencies");
            xlabel(ax6,"Frequency (kHz)");
            
        end
        
        function plotTime(app, TxSignal, data, t)
            %Function accepts Tx and Rx signals and plots time domain data.
            ax1 = subplot(2,1,1, 'parent', app.TimeDomainTab);
            plot(ax1, t, TxSignal); % plot transmit signal
            xlabel(ax1, 'Time (s)', 'fontsize', 12);
            ylabel(ax1, 'Amplitude (linear)', 'fontsize', 12);
            title(ax1,'Transmit signal', 'fontsize', 12);
            grid on;
            
            ax2 = subplot(2,1,2, 'parent', app.TimeDomainTab);
            plot(ax2, t, data); % plot received signal
            xlabel(ax2,'Time (s)', 'fontsize', 12);
            ylabel(ax2,'Amplitude (linear)', 'fontsize', 12);
            title(ax2,'Received signal', 'fontsize', 12);
            grid on;
        end
        
        function [max_v, avg_v, instant_v] = getVelocities(app, t_s, f, v_factor, S)
            %Calculate velocities (max, avg, instantaneous) as part of
            %custom STFT approach
            max_frequencies = zeros(length(t_s),1);
            for frame = 1:length(t_s)
                %gets max STFT magnitude in frame.
                [max_S, index] = max(S(:, frame)); 
                 %if higher than threshold and frequency is not higher than realistically observable
                if (max_S >= app.threshold) && (f(index) < app.TransmitFrequencyHzEditField.Value/4)
                    %store corresponding frequency value
                    max_frequencies(frame) = f(index); 
                else
                    max_frequencies(frame) = 0;
                end
            end
            instant_v = max_frequencies*v_factor;
            instant_v = smoothdata(instant_v,'movmean',3);
            max_v = max(instant_v);
            %obtain mean as a moving window across the data
            avg_v = max(movmean(instant_v, 5)); 
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            clc;
            %Method loads previously saved data in .mat form.
            fig = app.UIFigure;
            %Loading bar for user friendliness.
            d = uiprogressdlg(fig,'Title','Please Wait','Message','Loading dataset...');
            d.Value = 0.33;
            % read values from inputs -------------------------------------
            app.threshold = app.ThresholdEdit.Value;
            val = str2num(app.SamplesperFrameDropDown.Value);
            NumSamplesPerFrame = 2^(val);
            OverlapFactor = app.FrameOverlapEditField.Value;          
            try %In case loading from file fails, dont crash program...
                [file, path] = uigetfile();
                load([path, file], 'data', 'Fc_Hz', 'Fs', 'TimeDuration_s');

                app.TransmitFrequencyHzEditField.Value = Fc_Hz;
                app.SamplingTimesEditField.Value = TimeDuration_s;
                app.SamplingFrequencyDropDown.Value = num2str(Fs);
                % -------------------------------------------------------------
                Ts = 1/Fs;                           % Sampling period
                t = 0:Ts:(TimeDuration_s);           % time vector for pulse
                v_factor = (343/Fc_Hz)/2;            % Velocity conversion factor. f --> v
                TxSignal = sin(2*pi*Fc_Hz*t);        % Pure sinusoid to be transmitted  
                
                %Call processDoppler method which does initial signal
                %processing (BPF, notch filter, baseband conversion, downsample)
                [TxSignal, data, data_bpf, data_notch, data_shifted, data_out, t] = processDoppler(Fs, Fc_Hz, TimeDuration_s, TxSignal, data);
                
                %Create frequency axis for plotting
                N = length(t);
                deltaf = 1/(N*(1/Fs));
                if mod(N,2)==0    % case N even
                    f_axis = (-N/2:(N/2-1))*deltaf;    
                else   % case N odd
                    f_axis = (-(N-1)/2 : (N-1)/2)*deltaf; 
                end 
                
                %Create downsampled frequency axis for plotting
                N = length(data_out);
                deltaf = 1/(N*(8/Fs));
                if mod(N,2)==0    % case N even
                    f_axis_d = (-N/2:(N/2-1))*deltaf;    
                else   % case N odd
                    f_axis_d = (-(N-1)/2 : (N-1)/2)*deltaf; 
                end 
                Ts_d = 8*Ts; %down sample by a factor of 8
                
                d.Message = 'Processing data...';
                d.Value = 0.66;
                
                % data_out is now doppler shift data. Compute spectrogram
                % with custom method:
                [S, f, t_s] = Compute_STFT(data_out,Fs/8, NumSamplesPerFrame, OverlapFactor);
                
                %Plot raw data after processDoppler is applied:
                figure;
                imagesc(t_s,f.*v_factor,20*log10(abs(S)));
                ylim([1 15]);
                xlabel('Time (s)');
                ylabel('Doppler Velocity (m/s)');
%                 title('Raw signal received');
                grid on;
                colorbar;
                colormap('jet');
                set(gca,'YDir','normal') ;
                
                
                % despeckle by removing lonely points of noise (no
                % neighbouring high power noise)
                for i = 2:length(f)-1 
                    for j = 2:length(t_s)-1
                       if ((20*log10(abs(S(i,j-1))) <= app.threshold) && (20*log10(abs(S(i,j+1))) <= app.threshold)) || (20*log10(abs(S(i,j))) < app.threshold)
                           S(i,j) = 0.0000001; 
                       end
                    end
                end
                % Remove frequencies pointing to velocities lower than 1m/s
                % - no sports ball is realistically going to be travelling
                % at such a low speed.
                for i = 1:length(f)
                    if (f(i) < 5.83e-3*Fc_Hz) %5.83e-3 ignores things below 1m/s. 2.92e-3 ignores below 0.5 m/s
                        S(i, :) = 0.0000001; 
                    end
                end
                %Smooth STFT matrix to reduce effects of noise.
                S = smoothdata(S, 'movmean', [7 7]); 
                
                % Retrieve velocities from custom STFT data.
                [max_v, avg_v, instant_v] = getVelocities(app, t_s, f, v_factor, 20*log10(abs(S)));
                app.max.Text = num2str(max_v);
                app.avg.Text = num2str(avg_v);
                plot(app.UIAxes, t_s, instant_v);   
                yline(app.UIAxes, max_v, "r");
                yline(app.UIAxes, avg_v, "b--");
                
                 % Normalize data for plotting.
                datan = norm_db(S);
                datan = smoothdata(datan, 'movmean', [10 10]);       
                
                % Plot normalized spectrogram
                figure;
                imagesc(t_s,f.*v_factor,datan, [app.threshold-30, 0]);
                title("Normalized spectrogram output from custom algorithm");
                ylim([1 15])
                xlabel('Time (s)');
                ylabel('Doppler Velocity (m/s)');
                grid on;
                colorbar;
                colormap('jet');
                set(gca,'YDir','normal') 
                
                % ----------------pspectrum method ----------------------%
                [P1,F,T]=pspectrum(data_out,Fs/8,'spectrogram','FrequencyResolution',128, 'FrequencyLimits',[0 4000],'MinThreshold',app.pthreshold);
                % Plot pspectrum spectrogram:
                figure;
                pspectrum(data_out,Fs/8,'spectrogram','FrequencyResolution',128, 'FrequencyLimits',[0 4000], 'MinThreshold',app.pthreshold);
                title("Spectrogram of basket ball thrown from 6 metres");
                
                % Extract median frequencies:
                freqs = medfreq(P1, F);
                
                % Get velocity from frequencies:
                dv1 = freqs * v_factor;
                % Remove velocities under 1m/s
                for i = 1:length(dv1)
                    if dv1(i) < 1
                        dv1(i) = 0;
                    end
                end
                % Smooth velocity output and extract velocity metrics
                dv1= smoothdata(dv1,'movmean',2);
                max_speed = max(dv1,[],'omitnan');
                disp('Max: ');
                disp(max_speed);
                disp('Avg: ');
                avg = max(movmean(dv1, 2, 'omitnan'));
                disp(avg);
                % % 
                figure;
                hold on
                plot(T, dv1);
                yline(max_speed, 'r');
                yline(avg, 'b--');
                
                hold off
                title("Doppler velocity using median frequencies of power spectrum")

                plotFFT(app, TxSignal, data, data_bpf, data_notch, data_shifted, data_out, f_axis, f_axis_d, Ts, Ts_d);
                plotTime(app, TxSignal, data, t);
                
                d.Message = 'Complete!';
                d.Value = 1;
                pause(1);
                close(d);
                
            catch e
                disp("Please choose data to load!");
            end

        end

        % Button pushed function: RecordDataButton
        function RecordDataButtonPushed(app, event)
            clc;
            %Method records ball movement and processes doppler data

            % read values from inputs -------------------------------------
            app.threshold = app.ThresholdEdit.Value;
            val = str2num(app.SamplesperFrameDropDown.Value);
            NumSamplesPerFrame = 2^(val);
            OverlapFactor = app.FrameOverlapEditField.Value;
            Fc_Hz = app.TransmitFrequencyHzEditField.Value;
            app.threshold = app.ThresholdEdit.Value;
            TimeDuration_s = app.SamplingTimesEditField.Value;
            Fs = str2num(app.SamplingFrequencyDropDown.Value);
    
            % -------------------------------------------------------------
            Ts = 1/Fs;                           % Sampling period
            t = 0:Ts:(TimeDuration_s);           % time vector for pulse
            v_factor = (343/Fc_Hz)/2;            % Velocity conversion factor. f --> v
            TxSignal = sin(2*pi*Fc_Hz*t);        % Pure sinusoid to be transmitted  
            
            soundsc(TxSignal,Fs, 24); % Transmit the signal. 24 is resolution in bits
            %Loading bar for user friendliness.
            fig = app.UIFigure;
            d = uiprogressdlg(fig,'Title','Please Wait','Message','Transmitting and receiving...');
            d.Value = 0.33;
            
            % Setup recording and sound card.
            RecLength_samples = length(TxSignal);
            RecLength_s = RecLength_samples*1/Fs; 
            recObj = audiorecorder(Fs,24,1); %Use only channel 1, 24 bits at Fs sample rate.
            recordblocking(recObj, RecLength_s);  % Records audio for a fixed number of seconds
            data = getaudiodata(recObj);   % Store recorded audio signal in double-precision array
            data(1:100)=0; % Cut beginning and end of data for startup time.
            data(end-100:end)=0;
            
            % Save recorded data or not?
            if app.SavedataCheckBox.Value == 1
                [file, path] = uiputfile('*.mat', 'Please enter a filename');
                try
                    save(fullfile(path, file), 'data', 'Fc_Hz', 'Fs', 'TimeDuration_s');
                catch e
                    disp("No file selected.");
                end
            end
            
            %Call processDoppler method which does initial signal
            %processing (BPF, notch filter, baseband conversion, downsample)    
            [TxSignal, data, data_bpf, data_notch, data_shifted, data_out, t] = processDoppler(Fs, Fc_Hz, TimeDuration_s, TxSignal, data);
            
            %Create frequency axis for plotting
            N = length(t);
            deltaf = 1/(N*(1/Fs));
            if mod(N,2)==0    % case N even
                f_axis = (-N/2:(N/2-1))*deltaf;    
            else   % case N odd
                f_axis = (-(N-1)/2 : (N-1)/2)*deltaf; 
            end 
            
            %Create downsampled frequency axis for plotting
            N = length(data_out);
            deltaf = 1/(N*(8/Fs));
            if mod(N,2)==0    % case N even
                f_axis_d = (-N/2:(N/2-1))*deltaf;    
            else   % case N odd
                f_axis_d = (-(N-1)/2 : (N-1)/2)*deltaf; 
            end 
            Ts_d = 8*Ts; %down sample by a factor of 8
            
            d.Message = 'Processing data...';
            d.Value = 0.66;
            
            % data_out is now doppler shift data. Compute spectrogram
            % with custom method:
            [S, f, t_s] = Compute_STFT(data_out,Fs/8, NumSamplesPerFrame, OverlapFactor);
            
            %Plot raw data after processDoppler is applied:
            figure;
            imagesc(t_s,f.*v_factor,20*log10(abs(S)));
            ylim([0 15]);
            xlabel('Time (s)');
            ylabel('Doppler Velocity (m/s)');
            title('Raw signal received');
            grid on;
            colorbar;
            colormap('jet');
            set(gca,'YDir','normal') 
            
            
            % despeckle by removing lonely points of noise (no
            % neighbouring high power noise)
            for i = 2:length(f)-1 
                for j = 2:length(t_s)-1
                   if ((20*log10(abs(S(i,j-1))) <= app.threshold) && (20*log10(abs(S(i,j+1))) <= app.threshold)) || (20*log10(abs(S(i,j))) < app.threshold)
                       S(i,j) = 0.0000001; 
                   end
                end
            end
            % Remove frequencies pointing to velocities lower than 1m/s
            % - no sports ball is realistically going to be travelling
            % at such a low speed.
            for i = 1:length(f)
                if (f(i) < 5.83e-3*Fc_Hz) %5.83e-3 ignores things below 1m/s. 2.92e-3 ignores below 0.5 m/s
                    S(i, :) = 0.0000001; 
                end
            end
            %Smooth STFT matrix to reduce effects of noise.
            S = smoothdata(S, 'movmean', [7 7]); 
            
            % Retrieve velocities from custom STFT data.
            [max_v, avg_v, instant_v] = getVelocities(app, t_s, f, v_factor, 20*log10(abs(S)));
            app.max.Text = num2str(max_v);
            app.avg.Text = num2str(avg_v);
            plot(app.UIAxes, t_s, instant_v);   
            yline(app.UIAxes, max_v, "r");
            yline(app.UIAxes, avg_v, "b--");
            
             % Normalize data for plotting.
            datan = norm_db(S);
%                 datan = smoothdata(datan, 'movmean', [10 10]);       
            
            % Plot normalized spectrogram
            figure;
            imagesc(t_s,f.*v_factor,datan, [app.threshold-30, 0]);
            title("Normalized spectrogram output from custom algorithm");
            ylim([0 15])
            xlabel('Time (s)');
            ylabel('Doppler Velocity (m/s)');
            grid on;
            colorbar;
            colormap('jet');
            set(gca,'YDir','normal');
            
            % ----------------pspectrum method ----------------------%
            [P1,F,T]=pspectrum(data_out,Fs/8,'spectrogram','FrequencyResolution',128, 'FrequencyLimits',[0 4000],'MinThreshold',app.pthreshold);
            % Plot pspectrum spectrogram:
            figure;
            pspectrum(data_out,Fs/8,'spectrogram','FrequencyResolution',128, 'FrequencyLimits',[0 4000],'MinThreshold',app.pthreshold);
            title("Spectrogram of basket ball thrown from 6 metres");
            
            % Extract median frequencies:
            freqs = medfreq(P1, F);
            
            % Get velocity from frequencies:
            dv1 = freqs * v_factor;
            % Remove velocities under 1m/s
            for i = 1:length(dv1)
                if dv1(i) < 1
                    dv1(i) = 0;
                end
            end
            % Smooth velocity output and extract velocity metrics
            dv1= smoothdata(dv1,'movmean',10);
            max_speed = max(dv1,[],'omitnan');
            disp('Max: ');
            disp(max_speed);
            disp('Avg: ');
            avg = max(movmean(dv1, 10, 'omitnan'));
            disp(avg);
            % % 
            figure;
            hold on
            plot(T, dv1);
            yline(max_speed, 'r');
            yline(avg, 'b--');
            
            hold off
            title("Doppler velocity using median frequencies of power spectrum");

            plotFFT(app, TxSignal, data, data_bpf, data_notch, data_shifted, data_out, f_axis, f_axis_d, Ts, Ts_d);
            plotTime(app, TxSignal, data, t);
            
            d.Message = 'Complete!';
            d.Value = 1;
            pause(1);
            close(d);
        end

        % Button pushed function: CalibrateButton
        function CalibrateButtonPushed(app, event)
            Fc_Hz = app.TransmitFrequencyHzEditField.Value;
            TimeDuration_s = app.SamplingTimesEditField.Value;
            Fs = str2num(app.SamplingFrequencyDropDown.Value);  
            val = str2num(app.SamplesperFrameDropDown.Value);
            NumSamplesPerFrame = 2^(val);
            OverlapFactor = app.FrameOverlapEditField.Value;
            Ts = 1/Fs;                           % Sampling period
            t = 0:Ts:(TimeDuration_s);           % time vector for pulse 
            % Pure sinusoid 
            TxSignal = sin(2*pi*Fc_Hz*t);
            
            thresholdValues = zeros(5,1);
            max_P = zeros(5,1);
            
            fig = app.UIFigure;
            d = uiprogressdlg(fig,'Title','Please Wait','Message','Calibrating...');
            
            %loop here 5 times to calibrate as an average of noise received
            for calibNr = 1:5
                d.Message = 'Transmitting and receiving...';          
                soundsc(TxSignal,Fs, 24) % Transmit the signal. 24 is resolution in bits   
                RecLength_samples = length(TxSignal);
                RecLength_s = RecLength_samples*1/Fs; 
                recObj = audiorecorder(Fs,24,1); %Use only channel 1
                recordblocking(recObj, RecLength_s);  % Records audio for a fixed number of seconds
                data = getaudiodata(recObj);   % Store recorded audio signal in double-precision array
                data(1:100)=0;
                data(end-100:end)=0;
                    
                [TxSignal, data, data_bpf, data_notch, data_shifted, data_out, t] = processDoppler(Fs, Fc_Hz, TimeDuration_s, TxSignal, data);
                d.Value = calibNr/6;
                d.Message = 'Processing data...';
                %y is now just the response from doppler shifts. Compute spectrogram.
                [S, f, t_s] = Compute_STFT(data_out,Fs/8, NumSamplesPerFrame, OverlapFactor);
                [P1,F,T]=pspectrum(data_out,Fs/8,'spectrogram','FrequencyResolution',128, 'FrequencyLimits',[0 4000]);
                P1 = pow2db(P1);
                v_factor = (343/Fc_Hz)/2;
                S(:, 1:10) = 0.0000001; %discard startup time values
                max_S = zeros(length(t_s),1);
                Ps = zeros(length(P1(1)),1);
                for frame = 1:length(t_s)
                    max_S(frame) = 20*log10(max(abs(S(:, frame)))); %gets max fourier domain magnitude in frame.    
                end
                for index = 1:length(Ps)
                    Ps(index) = max(P1(index));
                end
                max_P
                max_S = smoothdata(max_S, 'movmean', 5);
                thresholdValues(calibNr) = max(max_S);
                max_P(calibNr) = max(Ps);
            end
            disp("max");
            app.pthreshold = max(max_P);
            app.threshold = mean(thresholdValues)*0.9; %Add 10% to threshold to be sure.
            app.ThresholdEdit.Value = app.threshold;
            
            d.Value = 1;
            d.Message = 'Calibration complete!';
            pause(1);
            close(d);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            clear all;
            close all;
            clc;
            closereq
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Panel = uipanel(app.UIFigure);
            app.UserParametersLabel = uilabel(app.Panel);
            app.UserParametersLabel.Text = 'User Parameters';
            app.Panel2 = uipanel(app.Panel);
            app.Panel2.Title = 'Sampling parameters';
            app.SamplingFrequencyDropDownLabel = uilabel(app.Panel2);
            app.SamplingFrequencyDropDownLabel.Text = 'Sampling Frequency';
            app.SamplingFrequencyDropDown = uidropdown(app.Panel2);
            app.SamplingFrequencyDropDown.Items = {'44.1 kHz', '192  kHz'};
            app.SamplingFrequencyDropDown.ItemsData = {'44100', '192000'};
            app.SamplingFrequencyDropDown.Value = '192000';
            app.TransmitFrequencyHzEditFieldLabel = uilabel(app.Panel2);
            app.TransmitFrequencyHzEditFieldLabel.Text = 'Transmit Frequency (Hz)';
            app.TransmitFrequencyHzEditField = uieditfield(app.Panel2, 'numeric');
            app.TransmitFrequencyHzEditField.Limits = [0 Inf];
            app.TransmitFrequencyHzEditField.Value = 40000;
            app.SamplingTimesEditFieldLabel = uilabel(app.Panel2);
            app.SamplingTimesEditFieldLabel.Text = 'Sampling Time (s)';
            app.SamplingTimesEditField = uieditfield(app.Panel2, 'numeric');
            app.SamplingTimesEditField.Limits = [0 500];
            app.SamplingTimesEditField.Value = 5;
            app.Panel2_2 = uipanel(app.Panel);
            app.Panel2_2.Title = 'Spectrogram parameters';
            app.SamplesperFrameDropDownLabel = uilabel(app.Panel2_2);
            app.SamplesperFrameDropDownLabel.Text = 'Samples per Frame';
            app.SamplesperFrameDropDown = uidropdown(app.Panel2_2);
            app.SamplesperFrameDropDown.Items = {'2^12', '2^11', '2^10'};
            app.SamplesperFrameDropDown.ItemsData = {'12', '11', '10'};
            app.SamplesperFrameDropDown.Value = '12';
            app.FrameOverlapEditFieldLabel = uilabel(app.Panel2_2);
            app.FrameOverlapEditFieldLabel.Text = 'Frame Overlap (%)';
            app.FrameOverlapEditField = uieditfield(app.Panel2_2, 'numeric');
            app.FrameOverlapEditField.Limits = [0 1];
            app.FrameOverlapEditField.Value = 0.9;
            app.YAxisVariableDropDownLabel = uilabel(app.Panel2_2);
            app.YAxisVariableDropDownLabel.Text = 'Y Axis Variable';
            app.YAxisVariableDropDown = uidropdown(app.Panel2_2);
            app.YAxisVariableDropDown.Items = {'Frequency', 'Velocity'};
            app.YAxisVariableDropDown.ItemsData = {'0', '1'};
            app.YAxisVariableDropDown.Value = '0';
            app.ThresholddBLabel = uilabel(app.Panel2_2);
            app.ThresholddBLabel.Text = 'Threshold (dB)';
            app.ThresholdEdit = uieditfield(app.Panel2_2, 'numeric');
            app.ThresholdEdit.Limits = [-200 40];
            app.ThresholdEdit.Value = -80;
            app.LoadDataButton = uibutton(app.Panel, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.RecordDataButton = uibutton(app.Panel, 'push');
            app.RecordDataButton.ButtonPushedFcn = createCallbackFcn(app, @RecordDataButtonPushed, true);
            app.RecordDataButton.Text = 'Record Data';
            app.Image = uiimage(app.Panel);
            app.Image.ImageSource = 'uct.png';
            app.SavedataCheckBox = uicheckbox(app.Panel);
            app.SavedataCheckBox.Text = 'Save data?';
            app.CalibrateButton = uibutton(app.Panel, 'push');
            app.CalibrateButton.ButtonPushedFcn = createCallbackFcn(app, @CalibrateButtonPushed, true);
            app.CalibrateButton.Text = 'Calibrate';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.VelocityResultsTab = uitab(app.TabGroup);
            app.VelocityResultsTab.Title = 'Velocity Results';
            app.MaxSpeedmsLabel = uilabel(app.VelocityResultsTab);
            app.MaxSpeedmsLabel.Text = 'Max Speed (m/s):';
            app.max = uilabel(app.VelocityResultsTab);
            app.max.Text = '';
            app.AverageSpeedmsLabel = uilabel(app.VelocityResultsTab);
            app.AverageSpeedmsLabel.Text = 'Average Speed (m/s):';
            app.avg = uilabel(app.VelocityResultsTab);
            app.avg.Text = '';
            app.UIAxes = uiaxes(app.VelocityResultsTab);
            title(app.UIAxes, 'Speed vs Time')
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'Average Speed')
            zlabel(app.UIAxes, 'Z')
            app.TimeDomainTab = uitab(app.TabGroup);
            app.TimeDomainTab.Title = 'Time Domain';
            app.SignalProcessingTab = uitab(app.TabGroup);
            app.SignalProcessingTab.Title = 'Signal Processing';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Demonstrator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end