classdef Demonstrator_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Panel                         matlab.ui.container.Panel
        SettingsLabel                 matlab.ui.control.Label
        Panel2                        matlab.ui.container.Panel
        RecordingTimesEditFieldLabel  matlab.ui.control.Label
        RecordingTimesEditField       matlab.ui.control.NumericEditField
        RecordDataButton              matlab.ui.control.Button
        Image                         matlab.ui.control.Image
        TabGroup                      matlab.ui.container.TabGroup
        VelocityResultsTab            matlab.ui.container.Tab
        MaxSpeedmsLabel               matlab.ui.control.Label
        max                           matlab.ui.control.Label
        AverageSpeedmsLabel           matlab.ui.control.Label
        avg                           matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
        TextArea                      matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        Fc_Hz = 40e3; % Transmit frequency
        pthreshold = -80; % threshold for pspectrum method
        threshold = -82 % noise threshold is either -43 or -100 depending on acoustic SONAR or ultrasonic
    end
    
    methods (Access = private)
        function [max_v, avg_v, instant_v] = getVelocities(app, t_s, f, v_factor, S)
            %Calculate velocities (max, avg, instantaneous) as part of
            %custom STFT approach
            max_frequencies = zeros(length(t_s),1);
            for frame = 1:length(t_s)
                [max_S, index] = max(S(:, frame)); %gets max fourier domain magnitude in frame.
                if (max_S >= app.threshold) && (f(index) < app.Fc_Hz/4)
                    max_frequencies(frame) = f(index); %store corresponding frequency value
                else
                    max_frequencies(frame) = 0;
                end
            end
            instant_v = max_frequencies*v_factor;
            instant_v = smoothdata(instant_v,'movmean',3);
            max_v = max(instant_v);
            %obtain mean as a moving window across the data
            avg_v = max(movmean(instant_v, 5));
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RecordDataButton
        function RecordDataButtonPushed(app, event)
            %Method records ball movement and processes doppler data
            clc;
            
            % read values from inputs -------------------------------------
            TimeDuration_s = app.RecordingTimesEditField.Value;
            % -----------Set parameters from experimentation---------------
            Fs = 192e3;
            app.threshold = -20;
            NumSamplesPerFrame = 2^(12);
            OverlapFactor = 0.9;
            %--------------------------------------------------------------
            Ts = 1/Fs;                           % Sampling period
            t = 0:Ts:(TimeDuration_s);           % time vector for pulse
            v_factor = (343/app.Fc_Hz)/2;            % Velocity conversion factor. f --> v
            TxSignal = sin(2*pi*app.Fc_Hz*t);        % Pure sinusoid to be transmitted  
            
            soundsc(TxSignal,Fs, 24) % Transmit the signal. 24 is resolution in bits
            
            %Loading bar for user friendliness.
            fig = app.UIFigure;
            d = uiprogressdlg(fig,'Title','Please Wait','Message','Transmitting and receiving...');
            d.Value = 0.33;
            
            % Setup recording and sound card.
            RecLength_samples = length(TxSignal);
            RecLength_s = RecLength_samples*1/Fs; 
            recObj = audiorecorder(Fs,24,1); %Use only channel 1
            recordblocking(recObj, RecLength_s);  % Records audio for a fixed number of seconds
            data = getaudiodata(recObj);   % Store recorded audio signal in double-precision array
            data(1:100)=0; % Cut beginning and end of data for startup time.
            data(end-100:end)=0;
                
            %Call processDoppler method which does initial signal
            %processing (BPF, notch filter, baseband conversion, downsample)
            [TxSignal, data, data_bpf, data_notch, data_shifted, data_out, t] = processDoppler(Fs, app.Fc_Hz, TimeDuration_s, TxSignal, data);
            
            %Create frequency axis
            N = length(t);
            deltaf = 1/(N*(1/Fs));
            if mod(N,2)==0    % case N even
                f_axis = (-N/2:(N/2-1))*deltaf;    
            else   % case N odd
                f_axis = (-(N-1)/2 : (N-1)/2)*deltaf; 
            end 
            
            %Create downsampled frequency axis
            N = length(data_out);
            deltaf = 1/(N*(8/Fs));
            if mod(N,2)==0    % case N even
                f_axis_d = (-N/2:(N/2-1))*deltaf;    
            else   % case N odd
                f_axis_d = (-(N-1)/2 : (N-1)/2)*deltaf; 
            end 
            Ts_d = 8*Ts;
            
            d.Value = 0.66;
            d.Message = 'Processing data...';
            
            % data_out is now doppler shift data. Compute spectrogram
            % with custom method:
            [S, f, t_s] = Compute_STFT(data_out,Fs/8, NumSamplesPerFrame, OverlapFactor);

            % despeckle by removing lonely points of noise (no
            % neighbouring high power noise)
            for i = 2:length(f)-1
                for j = 2:length(t_s)-1
                   if ((20*log10(abs(S(i,j-1))) <= app.threshold) && (20*log10(abs(S(i,j+1))) <= app.threshold)) || (20*log10(abs(S(i,j))) < app.threshold)
                       S(i,j) = 0.0000001; 
                   end
                end
            end
            % Remove frequencies pointing to velocities lower than 1m/s
            % - no sports ball is realistically going to be travelling
            % at such a low speed.
            for i = 1:length(f)
                if (f(i) < 5.83e-3*app.Fc_Hz) %5.83e-3 ignores things below 1m/s. 2.92e-3 ignores below 0.5 m/s
                    S(i, :) = 0.0000001; 
                end
            end
            %Smooth STFT matrix to reduce effects of noise.
            S = smoothdata(S, 'movmean', [7 7]); 
           
            % Retrieve velocities from custom STFT data.
            [max_v, avg_v, instant_v] = getVelocities(app, t_s, f, v_factor, 20*log10(abs(S)));
            
            % ----------------pspectrum method ----------------------%   
            [P1,F,T]=pspectrum(data_out,Fs/8,'spectrogram','FrequencyResolution',128, 'FrequencyLimits',[0 4000],'MinThreshold',app.pthreshold);
            % Extract median frequencies:
            freqs = medfreq(P1, F);
            % Get velocity from frequencies:
            dv1 = freqs * v_factor;
            % Remove velocities under 1m/s
            for i = 1:length(dv1)
                if dv1(i) < 1
                    dv1(i) = 0;
                end
            end
            
            % Smooth velocity output and extract velocity metrics
            dv1= smoothdata(dv1,'movmean',2);
            max_speed = max(dv1,[],'omitnan');
            avg = max(movmean(dv1, 2, 'omitnan'));
            app.max.Text = num2str(max_speed);
            app.avg.Text = num2str(avg);
            plot(app.UIAxes, T, dv1);   
            yline(app.UIAxes, max_speed, "r");
            yline(app.UIAxes, avg, "b--");
            
            d.Message = 'Complete!';
            d.Value = 1;
            pause(1);
            close(d);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.SettingsLabel = uilabel(app.Panel);
            app.SettingsLabel.Text = 'Settings';
            app.Panel2 = uipanel(app.Panel);
            app.Panel2.Title = 'Sports RADAR Settings';
            app.RecordingTimesEditFieldLabel = uilabel(app.Panel2);
            app.RecordingTimesEditFieldLabel.Text = 'Recording Time (s)';
            app.RecordingTimesEditField = uieditfield(app.Panel2, 'numeric');
            app.RecordingTimesEditField.Limits = [0 500];
            app.RecordingTimesEditField.Value = 5;
            app.RecordDataButton = uibutton(app.Panel, 'push');
            app.RecordDataButton.ButtonPushedFcn = createCallbackFcn(app, @RecordDataButtonPushed, true);
            app.RecordDataButton.Text = 'Record Data';
            app.Image = uiimage(app.Panel);
            app.Image.ImageSource = 'uct.png';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.VelocityResultsTab = uitab(app.TabGroup);
            app.VelocityResultsTab.Title = 'Velocity Results';
            app.MaxSpeedmsLabel = uilabel(app.VelocityResultsTab);
            app.MaxSpeedmsLabel.Text = 'Max Speed (m/s):';
            app.max = uilabel(app.VelocityResultsTab);
            app.max.Text = '';
            app.AverageSpeedmsLabel = uilabel(app.VelocityResultsTab);
            app.AverageSpeedmsLabel.Text = 'Average Speed (m/s):';
            app.avg = uilabel(app.VelocityResultsTab);
            app.avg.Text = '';
            app.UIAxes = uiaxes(app.VelocityResultsTab);
            title(app.UIAxes, 'Speed vs Time')
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'Average Speed')
            zlabel(app.UIAxes, 'Z')
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'Instructions:'; ''; '1. Set the recording time, then click "Record activity".'; '2. After the time has elapsed, the speed metrics and graph of speed versus time will be displayed on the panel to the right.'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Demonstrator_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end